classdef saccues_trlrej < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        UIAxes          matlab.ui.control.UIAxes
        ButtonNext      matlab.ui.control.Button
        ButtonPrevious  matlab.ui.control.Button
        ButtonReject    matlab.ui.control.Button
        ButtonLoad      matlab.ui.control.Button
        ButtonSave      matlab.ui.control.Button
        ButtonAccept    matlab.ui.control.Button
        gototrialLabel  matlab.ui.control.Label
        EditStart       matlab.ui.control.NumericEditField
    end

    properties (Access = private)
        gaze      % gaze positions (x,y)
        t         % trial number
        evts      % timestamps
        bad_trls  % rejected trials
        data      % s (data struct)
        amp       % sacc amplitude
        time
        sacc_thresh
    end
    
    methods (Access = private)
        
    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: ButtonLoad
        function load(app, event)
            uiopen;

            app.data = s;
            app.time = s.time;
            app.bad_trls = s.badtrls;
            app.gaze = s.eyeraw(:,1:2,:);   % gaze position (1=gx, 2=gy)
            app.amp = s.eyeraw(:,4,:);   % saccade amplitude
            app.t = 1;      % start trial
            app.evts = s.eyemat;   % timestamps
            
            app.UIAxes.XLim = [0,app.time(end)];
            app.UIAxes.YLim = [-12,12];
            app.sacc_thresh = [[7,7];[-7,-7]];
            
            if isempty(app.bad_trls)
                app.bad_trls = 0;
            else
                app.t = max(app.bad_trls);
            end 
                        
            plot(app.UIAxes, app.time(1):app.time(end), [squeeze(app.gaze(app.t, 1:2, :))' squeeze(app.amp(app.t, :))'],'LineWidth', 2);
            app.UIAxes.Title.String = sprintf('trial #%d', app.t);
            hold(app.UIAxes,'on');
            plot(app.UIAxes, app.UIAxes.XLim, app.sacc_thresh(1,:), 'LineStyle', ':', 'Color', [0.50,0.50,0.50]);   
            plot(app.UIAxes, app.UIAxes.XLim, app.sacc_thresh(2,:), 'LineStyle', ':', 'Color', [0.50,0.50,0.50]); 
            plot(app.UIAxes, app.UIAxes.XLim, [0, 0], 'LineStyle', ':', 'Color', [0.50,0.50,0.50]);  
            hold(app.UIAxes, 'off');
            
            if ismember(app.t, app.bad_trls) || ismember(app.t, app.data.badtrls)
               app.UIAxes.Color = [0.85,0.60,0.60];   
            else
               app.UIAxes.Color = [1,1,1];   
            end
        end

        % Button pushed function: ButtonNext
        function next(app, event)
            if app.t == size(app.gaze, 1)
                                
                plot(app.UIAxes, app.time(1):app.time(end), [squeeze(app.gaze(app.t, 1:2, :))' squeeze(app.amp(app.t, :))'],'LineWidth', 2);
                app.UIAxes.Title.String = sprintf('trial #%d', app.t);
                hold(app.UIAxes,'on');
                plot(app.UIAxes, app.UIAxes.XLim, app.sacc_thresh(1,:), 'LineStyle', ':', 'Color', [0.50,0.50,0.50]);   
                plot(app.UIAxes, app.UIAxes.XLim, app.sacc_thresh(2,:), 'LineStyle', ':', 'Color', [0.50,0.50,0.50]); 
                plot(app.UIAxes, app.UIAxes.XLim, [0, 0], 'LineStyle', ':', 'Color', [0.50,0.50,0.50]);  
                hold(app.UIAxes, 'off');
                
                if ismember(app.t, app.bad_trls) || ismember(app.t, app.data.badtrls)
                   app.UIAxes.Color = [0.85,0.60,0.60];   
                else
                   app.UIAxes.Color = [1,1,1];   
                end
            
            else
                
                app.t = app.t +1;
                
                plot(app.UIAxes, app.time(1):app.time(end), [squeeze(app.gaze(app.t, 1:2, :))' squeeze(app.amp(app.t, :))'],'LineWidth', 2);
                app.UIAxes.Title.String = sprintf('trial #%d', app.t);
                hold(app.UIAxes,'on');
                plot(app.UIAxes, app.UIAxes.XLim, app.sacc_thresh(1,:), 'LineStyle', ':', 'Color', [0.50,0.50,0.50]);   
                plot(app.UIAxes, app.UIAxes.XLim, app.sacc_thresh(2,:), 'LineStyle', ':', 'Color', [0.50,0.50,0.50]); 
                plot(app.UIAxes, app.UIAxes.XLim, [0, 0], 'LineStyle', ':', 'Color', [0.50,0.50,0.50]);  
                hold(app.UIAxes, 'off');
            
                if ismember(app.t, app.bad_trls) || ismember(app.t, app.data.badtrls)
                   app.UIAxes.Color = [0.85,0.60,0.60];   
                else
                   app.UIAxes.Color = [1,1,1];   
                end
                
            end
        end

        % Button pushed function: ButtonPrevious
        function previous(app, event)
            if app.t == 1
                                
                plot(app.UIAxes, app.time(1):app.time(end), [squeeze(app.gaze(app.t, 1:2, :))' squeeze(app.amp(app.t, :))'],'LineWidth', 2);
                app.UIAxes.Title.String = sprintf('trial #%d', app.t);
                hold(app.UIAxes,'on');
                plot(app.UIAxes, app.UIAxes.XLim, app.sacc_thresh(1,:), 'LineStyle', ':', 'Color', [0.50,0.50,0.50]);   
                plot(app.UIAxes, app.UIAxes.XLim, app.sacc_thresh(2,:), 'LineStyle', ':', 'Color', [0.50,0.50,0.50]); 
                plot(app.UIAxes, app.UIAxes.XLim, [0, 0], 'LineStyle', ':', 'Color', [0.50,0.50,0.50]);  
                hold(app.UIAxes, 'off');
                
                if ismember(app.t, app.bad_trls) || ismember(app.t, app.data.badtrls)
                   app.UIAxes.Color = [0.85,0.60,0.60];   
                else
                   app.UIAxes.Color = [1,1,1];   
                end
                
            else
                
                app.t=app.t-1;
                
                plot(app.UIAxes, app.time(1):app.time(end), [squeeze(app.gaze(app.t, 1:2, :))' squeeze(app.amp(app.t, :))'],'LineWidth', 2);
                app.UIAxes.Title.String = sprintf('trial #%d', app.t);
                hold(app.UIAxes,'on');
                plot(app.UIAxes, app.UIAxes.XLim, app.sacc_thresh(1,:), 'LineStyle', ':', 'Color', [0.50,0.50,0.50]);   
                plot(app.UIAxes, app.UIAxes.XLim, app.sacc_thresh(2,:), 'LineStyle', ':', 'Color', [0.50,0.50,0.50]); 
                plot(app.UIAxes, app.UIAxes.XLim, [0, 0], 'LineStyle', ':', 'Color', [0.50,0.50,0.50]);  
                hold(app.UIAxes, 'off');
                
                if ismember(app.t, app.bad_trls) || ismember(app.t, app.data.badtrls)
                   app.UIAxes.Color = [0.85,0.60,0.60];   
                else
                   app.UIAxes.Color = [1,1,1];   
                end
                
            end
        end

        % Button pushed function: ButtonReject
        function reject(app, event)
            app.bad_trls(app.t) = app.t;
            app.UIAxes.Color = [0.85,0.60,0.60];
            next(app, event)
        end

        % Button pushed function: ButtonSave
        function savefile(app, event)
            s = app.data;
            s.badtrls = app.bad_trls(app.bad_trls>0);
            s.goodtrls = ones(1,size(s.eyeraw,1));
            s.goodtrls(s.badtrls) = 0;
            save(s.fname,'s');
            close(app.UIFigure);
        end

        % Button pushed function: ButtonAccept
        function accept(app, event)
            if length(app.bad_trls)>=app.t
               app.bad_trls(app.t) = [];
            end
            next(app, event)
        end

        % Key press function: UIFigure
        function keypress(app, event)
            switch event.Key
                case 'rightarrow'
                    next(app, event);
                case 'leftarrow'
                    previous(app, event);
                case 'r'
                    reject(app, event);
                case 'a'
                    accept(app, event);
                case 's'
                    savefile(app, event);
            end
        end

        % Value changed function: EditStart
        function EditStartValueChanged(app, event)
            app.t = app.EditStart.Value;
            app.t = app.t-1;
            next(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @keypress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'trial #n')
            xlabel(app.UIAxes, 'time from cue onset (ms)')
            ylabel(app.UIAxes, 'eye position (dva)')
            app.UIAxes.XLim = [0 400];
            app.UIAxes.YLim = [-12 12];
            app.UIAxes.XTick = [0 50 100 150 200 250 300 350 400];
            app.UIAxes.XTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'; '300'; '350'; '400'};
            app.UIAxes.YTick = [-12 -10 -8 -6 -4 -2 0 2 4 6 8 10 12];
            app.UIAxes.YTickLabel = {'-12'; '-10'; '-8'; '-6'; '-4'; '-2'; '0'; '2'; '4'; '6'; '8'; '10'; '12'};
            app.ButtonNext = uibutton(app.UIFigure, 'push');
            app.ButtonNext.ButtonPushedFcn = createCallbackFcn(app, @next, true);
            app.ButtonNext.Text = 'next >';
            app.ButtonPrevious = uibutton(app.UIFigure, 'push');
            app.ButtonPrevious.ButtonPushedFcn = createCallbackFcn(app, @previous, true);
            app.ButtonPrevious.Text = '< previous ';
            app.ButtonReject = uibutton(app.UIFigure, 'push');
            app.ButtonReject.ButtonPushedFcn = createCallbackFcn(app, @reject, true);
            app.ButtonReject.Text = 'reject';
            app.ButtonLoad = uibutton(app.UIFigure, 'push');
            app.ButtonLoad.ButtonPushedFcn = createCallbackFcn(app, @load, true);
            app.ButtonLoad.Text = 'load';
            app.ButtonSave = uibutton(app.UIFigure, 'push');
            app.ButtonSave.ButtonPushedFcn = createCallbackFcn(app, @savefile, true);
            app.ButtonSave.Text = 'save';
            app.ButtonAccept = uibutton(app.UIFigure, 'push');
            app.ButtonAccept.ButtonPushedFcn = createCallbackFcn(app, @accept, true);
            app.ButtonAccept.Text = 'accept';
            app.gototrialLabel = uilabel(app.UIFigure);
            app.gototrialLabel.Text = 'go to trial';
            app.EditStart = uieditfield(app.UIFigure, 'numeric');
            app.EditStart.Limits = [1 1200];
            app.EditStart.ValueChangedFcn = createCallbackFcn(app, @EditStartValueChanged, true);
            app.EditStart.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = saccues_trlrej

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end